classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        APPOXIMETERPanel      matlab.ui.container.Panel
        RegistosButton        matlab.ui.control.Button
        Image_4               matlab.ui.control.Image
        Label                 matlab.ui.control.Label
        Image_3               matlab.ui.control.Image
        Image_2               matlab.ui.control.Image
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        Image                 matlab.ui.control.Image
        PasseEditField        matlab.ui.control.EditField
        PasseEditFieldLabel   matlab.ui.control.Label
        UsurioEditField       matlab.ui.control.EditField
        UsurioEditFieldLabel  matlab.ui.control.Label
        EntrarButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        serialConnection      
        isConnected = false   
        currentBPM = [];      
        currentSpO2 = [];     
        currentTimestamps = datetime.empty; 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.APPOXIMETERPanel.Visible = false;
            app.currentBPM = [];
            app.currentSpO2 = [];
            app.currentTimestamps = datetime.empty; 
        end 

        function setupSerialConnection(app)
            try
                % 1. Ver portas disponíveis
                ports = serialportlist("available");
                if isempty(ports)
                    uialert(app.UIFigure, 'Arduino não encontrado!', 'Erro');
                    return;
                end
                
                app.serialConnection = serialport("COM3", 115200);
                configureTerminator(app.serialConnection, "LF");
                
                % 3. Configurar para receber dados automaticamente
                configureCallback(app.serialConnection, "terminator", @(src,~) app.readSerialData(src));
                
                app.isConnected = true;
                app.Label.Text = ['Bem-vindo ' app.UsurioEditField.Value ' (Conectado)'];
                
            catch
                uialert(app.UIFigure, 'Erro ao conectar!', 'Falha');
            end
        end

        function readSerialData(app, src)
                    try
                        % ler os números do Arduino 
                        data = readline(src);
                        valores = sscanf(data, "%d %d");
                        
                        % guarda valores
                        app.currentBPM(end+1) = valores(1);
                        app.currentSpO2(end+1) = valores(2);
                        app.currentTimestamps(end+1) = datetime('now');
                        
                        % 3. Atualiza gráficos
                        plot(app.UIAxes, app.currentTimestamps, app.currentBPM, '-o');
                        plot(app.UIAxes_2, app.currentTimestamps, app.currentSpO2, '-s');
                        
                        % 4. Atualiza arquivo a cada 10 medidas
                        if mod(numel(app.currentBPM), 10) == 0
                            app.saveUserData();
                        end
                        
                    catch
                        disp('Erro na leitura!');
                    end
           end


        function saveUserData(app)
            user = app.UsurioEditField.Value;
            user_file = fullfile(pwd, 'dados_utilizadores', user + ".mat");
            
            % Carregar dados existentes ou criar novos
            if exist(user_file, 'file')
                dados = load(user_file);
            else
                dados = struct();
            end
            
            % Atualizar com novos dados
            dados.bpm = app.currentBPM;
            dados.spo2 = app.currentSpO2;
            dados.timestamps = app.currentTimestamps;
            dados.password = app.PasseEditField.Value; % Manter a senha
            
            save(user_file, '-struct', 'dados');
        end

        % Button pushed function: EntrarButton
        function EntrarButtonPushed(app, event)
            user = app.UsurioEditField.Value;
            pass = app.PasseEditField.Value;

            dados_dir = fullfile(pwd, 'dados_utilizadores');
            if ~exist(dados_dir, 'dir')
                mkdir(dados_dir);
            end

            user_file = fullfile(dados_dir, user + ".mat");

            if exist(user_file, 'file')
            % Se já existir user verifica pass
                dados = load(user_file);
                if isfield(dados, 'password') && strcmp(dados.password, pass)
                app.APPOXIMETERPanel.Visible = true;

                app.UsurioEditField.Visible = false;
                app.PasseEditField.Visible = false;
                app.UsurioEditFieldLabel.Visible = false;
                app.PasseEditFieldLabel.Visible = false;
                app.EntrarButton.Visible = false;
                else
                    uialert(app.UIFigure, 'Palavra-passe incorreta.', 'Erro');
                end
            else
            % Novo user
            password = pass;
            bpm = [];   
            spo2 = [];

            save(user_file, 'password', 'bpm', 'spo2');
            uialert(app.UIFigure, 'Utilizador registado com sucesso!', 'Sucesso');

            
            app.APPOXIMETERPanel.Visible = true;

            app.UsurioEditField.Visible = false;
            app.PasseEditField.Visible = false;
            app.UsurioEditFieldLabel.Visible = false;
            app.PasseEditFieldLabel.Visible = false;
            app.EntrarButton.Visible = false;

            end 
            app.Label.Text = ['Bem-vindo ' user];

            app.setupSerialConnection();
   
        end

        % Button pushed function: RegistosButton
        function RegistosButtonPushed2(app, event)
            dados_dir = fullfile(pwd, 'dados_utilizadores');

            % lista users
            files = dir(fullfile(dados_dir, '*.mat'));
            nomes = erase({files.name}, '.mat');

            if isempty(nomes)
                uialert(app.UIFigure, 'Não existem registos ainda.', 'Aviso');
                return;
            end

            [indx, tf] = listdlg('PromptString', 'Escolha um utilizador:','SelectionMode', 'single','ListString', nomes);

            if tf == 1
                user_escolhido = nomes{indx};
                dados = load(fullfile(dados_dir, user_escolhido + ".mat"));

                if isfield(dados, 'bpm') && isfield(dados, 'spo2')
                    bpm = dados.bpm;
                    spo2 = dados.spo2;

                    % Gráfico BPM
                    plot(app.UIAxes, bpm, '-o', 'Color', 'b', 'LineWidth', 1.5);
                    title(app.UIAxes, ['BPM de ', user_escolhido]);

                    % Gráfico SPO2
                    plot(app.UIAxes_2, spo2, '-o', 'Color', 'r', 'LineWidth', 1.5);
                    title(app.UIAxes_2, ['SpO₂ de ', user_escolhido]);

                    uialert(app.UIFigure, ['Dados de ', user_escolhido, ' carregados.'], 'Info');
                else
                    uialert(app.UIFigure, 'Este utilizador não tem dados guardados.', 'Aviso');
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.serialConnection)
                delete(app.serialConnection);
            end
            
            if app.isConnected
                app.saveUserData();
            end

            delete(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EntrarButton = uibutton(app.UIFigure, 'push');
            app.EntrarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrarButtonPushed, true);
            app.EntrarButton.Text = 'Entrar';
            app.UsurioEditFieldLabel = uilabel(app.UIFigure);
            app.UsurioEditFieldLabel.Text = 'Usuário';
            app.UsurioEditField = uieditfield(app.UIFigure, 'text');
            app.UsurioEditField.Value = 'admin ';
            app.PasseEditFieldLabel = uilabel(app.UIFigure);
            app.PasseEditFieldLabel.Text = 'Passe';
            app.PasseEditField = uieditfield(app.UIFigure, 'text');
            app.PasseEditField.Value = '1234';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'spo2-line-icon-png.png');
            app.APPOXIMETERPanel = uipanel(app.UIFigure);
            app.APPOXIMETERPanel.Title = 'APP OXIMETER';
            app.UIAxes = uiaxes(app.APPOXIMETERPanel);
            title(app.UIAxes, 'BPM MÉDIO')
            app.UIAxes_2 = uiaxes(app.APPOXIMETERPanel);
            title(app.UIAxes_2, 'SPO2 MÉDIO')
            app.Image_2 = uiimage(app.APPOXIMETERPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '3476044.png');
            app.Image_3 = uiimage(app.APPOXIMETERPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'shutterstock_1236631984-scaled.jpg');
            app.Label = uilabel(app.APPOXIMETERPanel);
            app.Image_4 = uiimage(app.APPOXIMETERPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'spo2-line-icon-png.png');
            app.RegistosButton = uibutton(app.APPOXIMETERPanel, 'push');
            app.RegistosButton.ButtonPushedFcn = createCallbackFcn(app, @RegistosButtonPushed2, true);
            app.RegistosButton.Text = 'Registos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end